classdef SigProc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectInputTypeDropDown         matlab.ui.control.DropDown
        SelectInputTypeDropDownLabel    matlab.ui.control.Label
        inputpreviewlabel               matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        SelectModulationSchemeDropDown  matlab.ui.control.DropDown
        SelectModulationSchemeDropDownLabel  matlab.ui.control.Label
        SelectChannelCodingDropDown     matlab.ui.control.DropDown
        SelectChannelCodingDropDownLabel  matlab.ui.control.Label
        SNRdBSlider                     matlab.ui.control.Slider
        SNRdBSliderLabel                matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        SelectFileButton                matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: SelectInputTypeDropDown
        function SelectInputTypeDropDownOpening(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%.......... UPLOAD FILES ..............
            ipType = app.SelectInputTypeDropDown.Value;
            data = [];
            mod_data=[];


            extn=0;
            extn2=0;


            imrow = 0; 
            audiofs = 0;
            extn = 0; 


            if ipType == "Random Bits"
                data = str2num(app.inputpreviewlabel.Text);
                disp("Random bits taken");
            elseif ipType == "Textfile"
                fileID = fopen(app.inputpreviewlabel.Text, "r");
                data = fscanf(fileID, "%s");
                fclose(fileID);
                data = dec2bin(double(char(data)),8);
                data = data - '0';
                data = reshape(data, 1, []);
                disp("Textfile converted to bits");
            elseif ipType == "Image"
                inpimg = imread(app.inputpreviewlabel.Text);
                inpimg = inpimg(:,:,1);
                inpimg = im2gray(inpimg);
                imrow = size(inpimg, 1);
                data = inpimg;
                data = dec2bin(data, 8);
                data = data - '0';
                data = reshape(data, 1, []);
                disp("image converted to bits");
            elseif ipType == "Audio Signal"
                [data1,audiofs] = audioread(app.in3putpreviewlabel.Text);%audio_decoding
                data2=data1(:,1);
                data3=typecast(data2,'uint8');
                data4=dec2bin(data3);
                data5=double(data4-'0');
                data6=reshape(data5,1,[]);%audio_bitstream
                data = data6;
                disp("audio converted to bits");
            end
            
            
            % apply channel coding to bitstream
            codingType = app.SelectChannelCodingDropDown.Value;
            if codingType == "(7,4) Hamming Code"
                data = reshape(data, [], 4);
                data = encode(data, 7, 4);
                data = reshape(data, 1, []);
                disp("hamming encoding done");
            elseif codingType == "BCH (127,64) Code"
                extn = 64 - mod(length(data),64);
                if extn == 64
                    extn = 0;
                end
                zerobits = zeros(1,extn);
                data = [data zerobits]; % add extra zeros to make length of data 64x
                data = reshape(data, [], 64);
                data=gf(data);
                data = bchenc(data, 127, 64);
                data = double(data.x);
                data = reshape(data, 1, []);
                disp("bch encoding done");
            elseif codingType == "BCH (63,51) Code"
                extn = 51 - mod(length(data),51);
                if extn == 51
                    extn = 0;
                end
                zerobits = zeros(1,extn);
                data = [data zerobits]; % add extra zeros to make length of data 51x
                data = reshape(data, [], 51);
                data=gf(data);
                data = bchenc(data, 63, 51);
                data = double(data.x);
                data = reshape(data, 1, []);
                disp("bch encoding done");
            end
           
            
            % modulation of bitstream
            % todo 16FSK, 16QAM, 64FSK, 64QAM
            
            modulationType = app.SelectModulationSchemeDropDown.Value;
            if modulationType == "16FSK"
                M = 16; % Modulation order
                freqsep = 8; % Frequency separation (Hz)
                nsamp = 8; % Number of samples per symbol
                Fs=3200;
                extn2 = 4 - mod(length(data),4);
                if extn == 4
                    extn = 0;
                end
                zerobits = zeros(1,extn2);
                data = [data zerobits];
                data=reshape(data,[],4);
                disp(data)
                data=char(data+'0');
                disp(data)
                data=(bin2dec(data))';
                disp(data)
                mod_data = fskmod(data,M,freqsep,nsamp,Fs);
                disp("16FSK modulation done");


            elseif modulationType == "16QAM"
                M = 16; % Modulation order
                extn2 = 4 - mod(length(data),4);
                if extn == 4
                    extn = 0;
                end
                zerobits = zeros(1,extn2);
                data = [data zerobits];
                data=reshape(data,[],4);
                disp(data)
                data=char(data+'0');
                disp(data)
                data=(bin2dec(data))';
                disp(data)
                mod_data = qammod(data,M);
                disp("16QAM modulation done");
                
            elseif modulationType == "64FSK"
                M = 64; % Modulation order
                freqsep = 8; % Frequency separation (Hz)
                nsamp = 8; % Number of samples per symbol
                Fs=3200;
                extn2 = 6 - mod(length(data),6);
                if extn == 6
                    extn = 0;
                end
                zerobits = zeros(1,extn2);
                data = [data zerobits];
                data=reshape(data,[],6);
                disp(data)
                data=char(data+'0');
                disp(data)
                data=(bin2dec(data))';
                disp(data)
                mod_data = fskmod(data,M,freqsep,nsamp,Fs);
                disp("64FSK modulation done");

            elseif modulationType == "64QAM"
                extn2 = 7 - mod(length(data),7);
                if extn == 7
                    extn = 0;
                end
                zerobits = zeros(1,extn2);
                data = [data zerobits];
                data=reshape(data,[],7);
                disp(data)
                data=char(data+'0');
                disp(data)
                data=(bin2dec(data))';
                M = 128;
                mod_data = qammod(data,M);
                disp("64QAM modulation done");
            end
         
            % PLOT
            wc = 2 * pi * 1;
            plotfs = 1000;
         
            if modulationType == "16FSK"
                t = (0:length(mod_data)-1) / Fs;         
                plot(app.UIAxes3,t, real(mod_data), 'o-');
                % Calculate and plot the Power Spectral Density (PSD)
                
                [psd, freq] = periodogram(mod_data, [], [], Fs, 'centered', 'psd');
                plot(app.UIAxes2,freq, 10*log10(psd), 'LineWidth', 2);          
                                  
            elseif modulationType == "16QAM"
                P = [];
                for i = 1:numel(mod_data)
                    for count_t = 0:999
                        temp = real(mod_data(i))*cos(wc*((i-1)+0.001*count_t))+imag(mod_data(i))*sin(wc*((i-1)+0.001*count_t));
                        P = [P, temp];
                    end
                end
                t = 0:1/plotfs:numel(mod_data)-(1/plotfs);
                plot(app.UIAxes3,t,P);
                
                p1 = xcorr(P,P);
                p2 = fft(p1,1000);
                p3 = abs(p2) / 1000;
                faxis = linspace(-500,500,1000);
                plot(app.UIAxes2,faxis,p3);
                scatterplot(mod_data);

                %ber
                SNR=0:1:15;
                Ni=200;
                Nb=4;
                Pe_sim = zeros(1,length(SNR));
                x=[];
                for s = 1:length(SNR)
                    En = (10^(SNR(s)/10))/(5);
                    symbols_e = 0;
                    for i = 1:Ni
                        symbols = x(randi(M,1,Nb)).';                        
                        noise = (randn(1,Nb)' + 1j*randn(1,Nb)')/sqrt(En);
                        r_t = symbols + noise
                        det = real(r_t*conj(x))-(1/2)*Em;                
                        [val, ind] = max(det,[],2);            
                        symbols_h = x(ind).';
                        symbols_e = symbols_e + sum(symbols~=symbols_h)/Nb; % Symbol error rate or probability of symbol error for the current SNR value
                    end
                    Pe_sim(s) = symbols_e/Nb;
                end

                SNR_m = 10.^(SNR/10);
               % theoretical symbol error probability of M=4 PSK
                Pe_th = 2*qfunc(sqrt(SNR_m*log2(M)*pi^2/(M^2)));  
                figure
                semilogy(app.UIAxes4,SNR,Pe_sim,'-*b','Linewidth',1)
                hold on
                semilogy(app.UIAxes4,SNR,Pe_th,'-sr','Linewidth',1)
                grid on
                xlim([min(SNR) max(SNR)])
                ylim([10^-8 10^0])
                xlabel('SNR [dB]')
                ylabel('Probability of symbol error')
                legend(['Pe_{sim}, M = ', num2str(M)],['Pe_{th}, M = ', num2str(M)], 'Fontsize', 12)
                title(['M-ary PSK symbol-level simulation, M = ', num2str(M)])
                
            elseif modulationType == "64FSK"
                 t = (0:length(mod_data)-1) / Fs;
                
                
                plot(app.UIAxes3,t, real(mod_data), 'o-');
                title('16-FSK Modulated Signal');
                xlabel('Time (seconds)');
                ylabel('Amplitude');
                

                % Calculate and plot the Power Spectral Density (PSD)
                
                [psd, freq] = periodogram(mod_data, [], [], Fs, 'centered', 'psd');
                plot(app.UIAxes2,freq, 10*log10(psd), 'LineWidth', 2);
                title('Power Spectral Density (PSD) for 16-FSK');
                xlabel('Frequency (Hz)');
                ylabel('Power/Frequency (dB/Hz)');
              
                
            elseif modulationType == "64QAM"
                P = [];
                for i = 1:numel(mod_data)
                    for count_t = 0:999
                        temp = real(mod_data(i))*cos(wc*((i-1)+0.001*count_t))+imag(mod_data(i))*sin(wc*((i-1)+0.001*count_t));
                        P = [P, temp];
                    end
                end
                t = 0:1/plotfs:numel(mod_data)-(1/plotfs);
                plot(app.UIAxes3,t,P);
                
                p1 = xcorr(P,P);
                p2 = fft(p1,1000);
                p3 = abs(p2) / 1000;
%                 figure;
                faxis = linspace(-500,500,1000);
                plot(app.UIAxes2,faxis,p3);
                scatterplot(mod_data);
            end


            %AWGN noise
            mod_data=awgn(mod_data,app.SNRdBSlider.Value,'measured');
            

            %NEXT PART : 
            % demodulation of waveform 
           
            if modulationType == "16FSK"
                M = 16; % Modulation order
                freqsep = 8; % Frequency separation (Hz)
                nsamp = 8; % Number of samples per symbol
                Fs=3200;
                mod_data = fskdemod(mod_data,M,freqsep,nsamp,Fs);
                mod_data=dec2bin(mod_data);
                mod_data = mod_data(1:end-extn2);
                mod_data=reshape(mod_data,1,[]);
                mod_data=mod_data-'0';
                disp(mod_data)
                disp("16FSK demod done");
                if codingType == "(7,4) Hamming Code"
                    enn=7;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],7);
                    mod_data = decode(mod_data, 7, 4, "hamming/binary");
                    disp("16FSK hamming decoding done");
                elseif codingType == "BCH (127,64) Code"
                    enn=127;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],127);
                    mod_data=gf(mod_data);
                    mod_data = bchdec(mod_data, 127, 64);
                    mod_data = double(mod_data.x);
                    mod_data=reshape(mod_data,1,[]);
                    mod_data = mod_data(1:end-extn);
                    disp("16FSK bch decoding done");
                elseif codingType == "BCH (63,51) Code"
                    enn=63;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],63);
                    mod_data=gf(mod_data);
                    mod_data = bchdec(mod_data, 63, 51);
                    mod_data = double(mod_data.x);
                    mod_data=reshape(mod_data,1,[]);
                    mod_data = mod_data(1:end-extn);
                    disp("16FSK bch decoding done");
                end   
                mod_data=reshape(mod_data,1,[]);
                enn=8;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                mod_data=reshape(mod_data,[],8);
                mod_data=mod_data+'0';
                mod_data=bin2dec(char(mod_data))';
                if ipType == "Textfile"
                    mod_data = char(mod_data);
                    disp(mod_data)
                    uialert(app.UIFigure,mod_data,"Output");
                elseif ipType == "Image"
                    enn=imrow;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,imrow,[]);
                    mod_data = uint8(mod_data); %done
                    imshow(mod_data); 
                    
                elseif ipType == "Audio Signal"
                    mod_data = typecast(mod_data,'double');
                    mod_data = uint8(mod_data);
                    mod_data = typecast(mod_data,'double');
                    sound(mod_data,audiofs);
                    disp("HelloWorld");
                    
                elseif ipType == "Random Bits"
                    mod_data=(dec2bin(double(mod_data)));
                    mod_data=reshape(mod_data,1,[]);
                    disp(mod_data)
                    uialert(app.UIFigure,mod_data,"Output");
                end


            elseif modulationType == "16QAM"
                M = 16; % Modulation order
                mod_data = qamdemod(mod_data,M);
                mod_data=dec2bin(mod_data);
                mod_data = mod_data(1:end-extn2);
                mod_data=reshape(mod_data,1,[]);
                mod_data=mod_data-'0';
                disp("16QAM demod done");
                if codingType == "(7,4) Hamming Code"
                    enn=7;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],7);
                    mod_data = decode(mod_data, 7, 4, "hamming/binary");
                    disp("16QAM hamming decoding done");
                elseif codingType == "BCH (127,64) Code"
                    enn=127;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],127);
                    mod_data=gf(mod_data);
                    mod_data = bchdec(mod_data, 127, 64); 
                    mod_data = double(mod_data.x);
                    mod_data=reshape(mod_data,1,[]);
                    mod_data = mod_data(1:end-extn);
                    disp("16QAM bch decoding done");
                elseif codingType == "BCH (63,51) Code"
                    enn=63;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],63);
                    mod_data=gf(mod_data);
                    mod_data = bchdec(mod_data, 63, 51); 
                    mod_data = double(mod_data.x);
                    mod_data=reshape(mod_data,1,[]);
                    mod_data = mod_data(1:end-extn);
                    disp("16QAM bch decoding done");
                end   
                mod_data=reshape(mod_data,1,[]);
                mod_data=reshape(mod_data,[],8);
                mod_data=mod_data+'0';
                mod_data=bin2dec(char(mod_data))';
                if ipType == "Textfile"
                    mod_data = char(mod_data);
                    disp(mod_data)
                    uialert(app.UIFigure,mod_data,"Output");
                elseif ipType == "Image"
                    mod_data = reshape(mod_data,imrow,[]);
                    mod_data = uint8(mod_data); %done
                    imshow(mod_data);
                    
                elseif ipType == "Audio Signal"
                    mod_data = typecast(mod_data,'double');
                    mod_data = uint8(mod_data);
                    mod_data = typecast(mod_data,'double');
                    sound(mod_data,audiofs);
                    disp("HelloWorld");
                    
                elseif ipType == "Random Bits"
                    mod_data=(dec2bin(double(mod_data)));
                    mod_data=reshape(mod_data,1,[]);
                    disp(mod_data);
                    uialert(app.UIFigure,mod_data,"Output");
                end
                
                
            elseif modulationType == "64FSK"
                M = 64; % Modulation order
                freqsep = 10; % Frequency separation (Hz)
                nsamp = 8; % Number of samples per symbol
                Fs=3200;
                mod_data = fskdemod(mod_data,M,freqsep,nsamp,Fs);
                mod_data=dec2bin(mod_data);
                mod_data = mod_data(1:end-extn2);
                mod_data=reshape(mod_data,1,[]);
                mod_data=mod_data-'0';
                disp(mod_data)
                disp("16FSK demod done");
                if codingType == "(7,4) Hamming Code"
                    enn=7;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];

                    mod_data = reshape(mod_data,[],7);
                    mod_data = decode(mod_data, 7, 4, "hamming/binary");
                    disp("64FSK hamming decoding done");
                elseif codingType == "BCH (127,64) Code"
                    enn=127;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],127);
                    mod_data=gf(mod_data);
                    mod_data = bchdec(mod_data, 127, 64);
                    mod_data = double(mod_data.x);
                    mod_data=reshape(mod_data,1,[]);
                    mod_data = mod_data(1:end-extn);
                    disp("64FSK bch decoding done");
                elseif codingType == "BCH (63,51) Code"
                    enn=63;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],63);
                    mod_data=gf(mod_data);
                    mod_data = bchdec(mod_data, 63, 51);
                    mod_data = double(mod_data.x);
                    mod_data=reshape(mod_data,1,[]);
                    mod_data = mod_data(1:end-extn);
                    disp("64FSK bch decoding done");
                end   
                mod_data=reshape(mod_data,1,[]);
                enn=8;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                mod_data=reshape(mod_data,[],8);
                mod_data=mod_data+'0';
                mod_data=bin2dec(char(mod_data))';
                if ipType == "Textfile"
                    mod_data = char(mod_data);
                    disp(mod_data)
                    uialert(app.UIFigure,mod_data,"Output");
                elseif ipType == "Image"
                    mod_data = reshape(mod_data,imrow,[]);
                    mod_data = uint8(mod_data); %done
                    imshow(mod_data); 
                    
                elseif ipType == "Audio Signal"
                    mod_data = typecast(mod_data,'double');
                    mod_data = uint8(mod_data);
                    mod_data = typecast(mod_data,'double');
                    sound(mod_data,audiofs);
                    disp("HelloWorld");
                    
                elseif ipType == "Random Bits"
                    mod_data=(dec2bin(double(mod_data)));
                    mod_data=reshape(mod_data,1,[]);
                    disp(mod_data)
                    uialert(app.UIFigure,mod_data,"Output");
                end

            elseif modulationType == "64QAM"
                M = 64; % Modulation order
                mod_data = qamdemod(mod_data,M);
                mod_data=dec2bin(mod_data);
                mod_data = mod_data(1:end-extn2);
                mod_data=reshape(mod_data,1,[]);
                mod_data=mod_data-'0';
                disp(mod_data)
                disp("64QAM demod done");
                if codingType == "(7,4) Hamming Code"
                    %mod_data = reshape(mod_data,[],7);
                    mod_data = decode(mod_data, 7, 4, "hamming/binary");
                    disp("64QAM hamming decoding done");
                elseif codingType == "BCH (127,64) Code"
                    enn=127;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],127);
                    mod_data=  gf(mod_data);
                    mod_data = bchdec(mod_data, 127, 64); 
                    mod_data = double(mod_data.x);
                    mod_data=reshape(mod_data,1,[]);
                    mod_data = mod_data(1:end-extn);
                    disp("64QAM bch decoding done");
                elseif codingType == "BCH (63,51) Code"
                    enn=63;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                    mod_data = reshape(mod_data,[],63);
                    mod_data=  gf(mod_data);
                    mod_data = bchdec(mod_data, 63, 51); 
                    mod_data = double(mod_data.x);
                    mod_data=reshape(mod_data,1,[]);
                    mod_data = mod_data(1:end-extn);
                    disp("64QAM bch decoding done");
                end   
                mod_data=reshape(mod_data,1,[]);
                enn=8;
                    numElementsToFill = enn- rem(numel(mod_data),enn);
                    mod_data=[mod_data,zeros(1,numElementsToFill)];
                mod_data=reshape(mod_data,[],8);
                mod_data=mod_data+'0';
                mod_data=bin2dec(char(mod_data))';
                if ipType == "Textfile"
                    mod_data = num2str(mod_data);
                    disp(mod_data)
                    uialert(app.UIFigure,mod_data,"Output");
                elseif ipType == "Image"
                    mod_data = reshape(mod_data,imrow,[]);
                    mod_data = uint8(mod_data); %done
                    imshow(mod_data);
                    
                elseif ipType == "Audio Signal"
                    mod_data = typecast(mod_data,'double');
                    mod_data = uint8(mod_data);
                    mod_data = typecast(mod_data,'double');
                    sound(mod_data,audiofs);
                    disp("HelloWorld");
                    
                elseif ipType == "Random Bits"
                    mod_data=(dec2bin(double(mod_data)));
                    mod_data=reshape(mod_data,1,[]);
                    disp(mod_data);
                    uialert(app.UIFigure,mod_data,"Output");
                end


            end
%----------------------------------------------------------------------
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            fileExtensions="";
            ipType=app.SelectipTypeDropDown.Value;
            if ipType == "Textfile"
                fileExtensions= "*.txt";
            elseif ipType == "Image"
                fileExtensions= "*.png";
            elseif ipType == "Audio Signal"
                fileExtensions= "*.mp3";
            end
            [file,path]=uigetfile(fileExtensions);
            if file ~=0
                app.inputpreviewlabel.Text=strcat(path,file);
            end
        end

        % Value changed function: SelectInputTypeDropDown
        function SelectInputTypeDropDownValueChanged(app, event)
            ipType = app.SelectInputTypeDropDown.Value;
            if ipType == "Random Bits"
                app.SelectFileButton.Enable=0;
                inputdata=randi([0 1],1,40);
                app.inputpreviewlabel.Text=num2str(inputdata);
            else
                app.SelectFileButton.Enable=1;
                app.inputpreviewlabel.Text="";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'colorcube');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Time Domain')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            colormap(app.UIAxes3, 'colorcube')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Power Spectral Density')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'colorcube')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'BER')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            colormap(app.UIAxes4, 'colorcube')
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Enable = 'off';
            app.SelectFileButton.Text = 'Select File';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SNRdBSliderLabel = uilabel(app.UIFigure);
            app.SNRdBSliderLabel.Text = 'SNR(dB)';
            app.SNRdBSlider = uislider(app.UIFigure);
            app.SNRdBSlider.Limits = [0 30];
            app.SNRdBSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.SNRdBSlider.MajorTickLabels = {'0', '', '2', '', '4', '', '6', '', '8', '', '10', '', '12', '', '14', '', '16', '', '18', '', '20', '', '22', '', '24', '', '26', '', '28', '', '30'};
            app.SNRdBSlider.MinorTicks = [];
            app.SNRdBSlider.Value = 2;
            app.SelectChannelCodingDropDownLabel = uilabel(app.UIFigure);
            app.SelectChannelCodingDropDownLabel.Text = 'Select Channel Coding';
            app.SelectChannelCodingDropDown = uidropdown(app.UIFigure);
            app.SelectChannelCodingDropDown.Items = {'Select', 'No Coding', '(7,4) Hamming Code', 'BCH (127,64) Code', 'BCH (63,51) Code'};
            app.SelectChannelCodingDropDown.Value = 'Select';
            app.SelectModulationSchemeDropDownLabel = uilabel(app.UIFigure);
            app.SelectModulationSchemeDropDownLabel.Text = 'Select Modulation Scheme';
            app.SelectModulationSchemeDropDown = uidropdown(app.UIFigure);
            app.SelectModulationSchemeDropDown.Items = {'Select', '16FSK', '16QAM', '64FSK', '64QAM'};
            app.SelectModulationSchemeDropDown.Value = 'Select';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.inputpreviewlabel = uilabel(app.UIFigure);
            app.inputpreviewlabel.Text = '';
            app.SelectInputTypeDropDownLabel = uilabel(app.UIFigure);
            app.SelectInputTypeDropDownLabel.Text = 'Select Input Type';
            app.SelectInputTypeDropDown = uidropdown(app.UIFigure);
            app.SelectInputTypeDropDown.Items = {'Select', 'Image', 'Random Bits', 'Textfile', 'Audio Signal'};
            app.SelectInputTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectInputTypeDropDownOpening, true);
            app.SelectInputTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectInputTypeDropDownValueChanged, true);
            app.SelectInputTypeDropDown.Value = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SigProc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end